classdef curs < matlab.apps.AppBase

    % Properties that correspond to app components
    properties (Access = public)
        UIFigure  matlab.ui.Figure
        TextArea  matlab.ui.control.TextArea
        Label_4   matlab.ui.control.Label
        Label_3   matlab.ui.control.Label
        Label_2   matlab.ui.control.Label
        image     matlab.ui.control.Image
        Button_2  matlab.ui.control.Button
        Button    matlab.ui.control.Button
        UIAxes    matlab.ui.control.UIAxes
    end

    
    properties (Access = private)
        img
        I2
    end
    

    % Callbacks that handle component events
    methods (Access = private)

        % Button pushed function: Button
        function ButtonPushed(app, event)
            app.Button.BackgroundColor = [0.45,0.62,0.48];
             app.Button.BackgroundColor = [0.63,0.86,0.67];
            app.img = imread('1.jpg');
            imshow(app.img, 'Parent', app.UIAxes);
        end

        % Button pushed function: Button_2
        function Button_2Pushed(app, event)
            app.Button.BackgroundColor = [0.45,0.62,0.48];
            app.Button.BackgroundColor = [0.63,0.86,0.67];

            app.image.Visible = 'on';

            Input = rgb2gray(im2double(app.img)); 
            Input = histeq(Input);
            Input = adapthisteq(Input,'clipLimit',0.02,'Distribution','rayleigh');
            Input = imsharpen(Input);
        
            threshold = graythresh(Input);  
            BW = imbinarize(Input,threshold);

            Icorrected = imtophat(Input,strel("disk",15)); 
            Icorrected = double(BW);
            BW1 = imbinarize(Icorrected);
            marker = imerode(Icorrected,strel("line",10,0)); 
            Iclean = imreconstruct(marker,Icorrected);
            Ibinary = imbinarize(Iclean);
            BW2 = imcomplement(Ibinary); 

            cc = bwconncomp(Ibinary); 
            stats = regionprops(cc, ["BoundingBox","Area"]); 
            roi = vertcat(stats(:).BoundingBox); 
            area = vertcat(stats(:).Area);
            app.img = insertObjectAnnotation(Input,"rectangle",roi,area,"LineWidth",3); 
            areaConstraint = area > 1000 & area < 50000;
            roi = double(roi(areaConstraint,:)); 
            app.img = insertShape(Input,"rectangle",roi);

            I = Input;
            width  = roi(:,3);
            height = roi(:,4);
            aspectRatio0 = height ./ width;
            filterIdx0 = aspectRatio0 > 0.13 & aspectRatio0 < 0.5;
            roi = roi( filterIdx0 ,:);
            app.img = insertShape(I,"rectangle",roi);
            picture = bwareaopen(~BW,30); 
            [~,cc]=size(app.img); 
            if cc>2000
                picture1=bwareaopen(picture,3500);
            else
                picture1=bwareaopen(picture,3000);
            end
            picture2=picture-picture1;
            picture2=bwareaopen(picture2,50);
            [L,Ne]=bwlabel(picture2); 
            propied=regionprops(L,'BoundingBox'); 
            bboxes0 = vertcat(propied.BoundingBox); 
            w0 = bboxes0(:,3);
            h0 = bboxes0(:,4);
            aspectRatio0 = w0./h0; 
            filterIdx0 = aspectRatio0' > 3;
            filt0 = bboxes0(:,1) > size(I,2)*0.1 & bboxes0(:,1)+bboxes0(:,3) < size(I,2)*0.9 ...
            & bboxes0(:,2) > size(I,1)*0.1 & bboxes0(:,2)+bboxes0(:,4) < size(I,1)*0.9;
            bboxes0 = bboxes0(filt0,:);

            [mserRegions, mserConnComp] = detectMSERFeatures(I, ...
                "RegionAreaRange",[200 8000],"ThresholdDelta",4);
            mserStats = regionprops(mserConnComp, "BoundingBox", "Eccentricity", ...
                "Solidity", "Extent", "Euler", "Image");
            bbox = vertcat(mserStats.BoundingBox);
            w = bbox(:,3);
            h = bbox(:,4);
            aspectRatio = w./h;
            filterIdx = aspectRatio' > 3;
            filterIdx = filterIdx | [mserStats.Eccentricity] > .995 ;
            filterIdx = filterIdx | [mserStats.Solidity] < .3;
            filterIdx = filterIdx | [mserStats.Extent] < 0.2 | [mserStats.Extent] > 0.9;
            filterIdx = filterIdx | [mserStats.EulerNumber] < -4;
            mserStats(filterIdx) = [];
            mserRegions(filterIdx) = [];
            bboxes = vertcat(mserStats.BoundingBox);
            if(length(bboxes) ~= 0) 
                filt = bboxes(:,1) > size(I,2)*0.1 & bboxes(:,1)+bboxes(:,3) < size(I,2)*0.9 ...
                    & bboxes(:,2) > size(I,1)*0.1 & bboxes(:,2)+bboxes(:,4) < size(I,1)*0.9;
                bboxes = bboxes(filt,:);
            end
            
            final_bbox = vertcat(bboxes0,bboxes);
            max2 = 0;
            sum2 = 0;
            ind_i_max = 0;
            for i = 1:length(roi(:,1))
              for j = 1:length(final_bbox(:,1))
                  if ((final_bbox(j,1)+final_bbox(j,3)/2)>roi(i,1) && (final_bbox(j,1) ...
                          +final_bbox(j,3)/2)<roi(i,1)+roi(i,3) && final_bbox(j,1)+3>roi(i,1) ...
                          && final_bbox(j,1)+final_bbox(j,3)-3<roi(i,1)+roi(i,3))
                      if((final_bbox(j,2)+final_bbox(j,4)/2)>roi(i,2)+roi(i,4)*0.4 ...
                              && (final_bbox(j,2)+final_bbox(j,4)/2)<roi(i,2)+roi(i,4)*0.6 && ...
                              final_bbox(j,2)+3>roi(i,2) && final_bbox(j,2)+final_bbox(j,4)-3<roi(i,2)+roi(i,4))
                          sum2 = sum2 + 1;
                      end
                  end
              end
              if(sum2 > max2)
                  max2 = sum2;
                  ind_i_max = i;
              end
              sum2 = 0;
            end
            if ind_i_max ~= 0
            app.img = insertShape(app.img,"rectangle",roi(ind_i_max,:));
            app.I2 = imcrop(app.img,roi(ind_i_max,:));
            app.image.ImageSource = app.I2;
            I3 = imcrop(picture2,roi(ind_i_max,:));
            [L2,Ne2]=bwlabel(I3); 
            propied2=regionprops(L2,'BoundingBox');
            results = ocr(I3); 
            txt = results.Text;
            licence_plate = regexprep(txt, '[^A-Z0-9]', '');
            app.TextArea.Value = num2str(licence_plate);
            end
        
        end

        % Callback function
        function EditFieldValueChanged(app, event)
           
        end
    end

    % Component initialization
    methods (Access = private)

        % Create UIFigure and components
        function createComponents(app)
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            app.UIFigure = uifigure('Visible', 'off');
            app.UIFigure.Name = 'MATLAB App';
            app.UIAxes = uiaxes(app.UIFigure);
            app.UIAxes.XTick = [];
            app.UIAxes.YTick = [];
            app.UIAxes.ZTick = [];
            app.Button = uibutton(app.UIFigure, 'push');
            app.Button.ButtonPushedFcn = createCallbackFcn(app, @ButtonPushed, true);
            app.Button.Text = 'Исходник';
            app.Button_2 = uibutton(app.UIFigure, 'push');
            app.Button_2.ButtonPushedFcn = createCallbackFcn(app, @Button_2Pushed, true);
            app.Button_2.Text = 'Найти номер';
            app.image = uiimage(app.UIFigure);
            app.image.Visible = 'off';
            app.Label_2 = uilabel(app.UIFigure);
            app.Label_2.Text = 'Исходное изображение';
            app.Label_3 = uilabel(app.UIFigure);
            app.Label_3.Text = 'Номер машины (изобр.)';
            app.Label_4 = uilabel(app.UIFigure);
            app.Label_4.Text = 'Номер машины (текст)';
            app.TextArea = uitextarea(app.UIFigure);
            % Show the figure after all components are created
            app.UIFigure.Visible = 'on';
        end
    end

    % App creation and deletion
    methods (Access = public)

        % Construct app
        function app = curs

            % Create UIFigure and components
            createComponents(app)

            % Register the app with App Designer
            registerApp(app, app.UIFigure)

            if nargout == 0
                clear app
            end
        end

        % Code that executes before app deletion
        function delete(app)

            % Delete UIFigure when app is deleted
            delete(app.UIFigure)
        end
    end
end